classdef CreateAcct < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CreateYourAccountLabel       matlab.ui.control.Label
        EnterYourFirstNameEditFieldLabel  matlab.ui.control.Label
        EnterYourFirstNameEditField  matlab.ui.control.EditField
        UploadImageButton            matlab.ui.control.Button
        UploadImageLabel             matlab.ui.control.Label
        EmbeddedCreateAccountButton  matlab.ui.control.Button
        CreateAccountImageFilepath   matlab.ui.control.Label
    end

    properties (Access = public)
        CreateAcctResponseLabel     matlab.ui.control.Label
        AccountCreatedSuccessfullyMessage matlab.ui.control.Label
        CreateAccountImageFile
    end


    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [createAccountImageFile, createAccountImageFilepath] = uigetfile('*.jpg');
            app.CreateAccountImageFilepath.Text = strcat(createAccountImageFilepath,createAccountImageFile);
            app.CreateAccountImageFile.Text = createAccountImageFile;
        end

        % Button pushed function: EmbeddedCreateAccountButton
        function EmbeddedCreateAccountButtonPushed(app, event)
            source = app.CreateAccountImageFilepath.Text;
            myDir = '/Users/justinlittle/MATLAB/Identiface/imgs';
            movefile(source, myDir);
            
            f = fullfile(myDir, '*.jpg');
            disp(f);
            theFiles = dir(f);
            disp(theFiles);
            vector_img = zeros(28^2, length(theFiles));
            s = struct('name', 'dataset');
            
            for k = 1 : length(theFiles)
                baseFileName = theFiles(k).name;
                fullFileName = fullfile(myDir, baseFileName);
                fprintf(1, 'Now reading %s\n', fullFileName); %prints the current image being processed.
                img = imread(fullFileName); %reads image
                img_gray = rgb2gray(img); %turns image to grayscale
                img_28 = imresize(img_gray, [28 28]); %resizes image
                vector_img(:,k) = img_28(:); %vectorizes the image
                s.name = app.EnterYourFirstNameEditField.Value
                s.dataset = vector_img(:,k);
            end

            csvwrite('imgdata.csv', vector_img, 1); %writes the vectorized images to csv file
            T = struct2table(s, 'name', 'dataset');
            disp(T);
            openfig(CreateAcctResponse);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CreateYourAccountLabel = uilabel(app.UIFigure);
            app.CreateYourAccountLabel.Text = 'Create Your Account!';
            app.EnterYourFirstNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterYourFirstNameEditFieldLabel.Text = 'Enter Your First Name:';
            app.EnterYourFirstNameEditField = uieditfield(app.UIFigure, 'text');
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Browse...';
            app.UploadImageLabel = uilabel(app.UIFigure);
            app.UploadImageLabel.Text = 'Upload an image of yourself!';
            app.EmbeddedCreateAccountButton = uibutton(app.UIFigure, 'push');
            app.EmbeddedCreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @EmbeddedCreateAccountButtonPushed, true);
            app.EmbeddedCreateAccountButton.Text = 'Create Account!';
            app.CreateAccountImageFilepath = uilabel(app.UIFigure);
            app.CreateAccountImageFilepath.Text = 'No Image Added';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CreateAcct

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end