classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoginLabel              matlab.ui.control.Label
        EnterYourFirstNameEditFieldLabel  matlab.ui.control.Label
        LoginFirstNameField     matlab.ui.control.EditField
        UploadLoginImageButton  matlab.ui.control.Button
        UploadLoginImageLabel   matlab.ui.control.Label
        EmbeddedLoginButton     matlab.ui.control.Button
        LoginImageFilepath      matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: EmbeddedLoginButton
        function EmbeddedLoginButtonPushed(app, event)
            success = true;
            failure = false;
            if success
                openfig(LoginSuccessful);
            else
                openfig(LoginFailure);
            end
        end

        % Button pushed function: UploadLoginImageButton
        function UploadLoginImageButtonPushed(app, event)
            [loginImageFile, loginImageFilePath] = uigetfile('*.jpg;*.jpeg');
            app.LoginImageFilepath.Text = strcat(loginImageFilePath,loginImageFile);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoginLabel = uilabel(app.UIFigure);
            app.LoginLabel.Text = 'Log In';
            app.EnterYourFirstNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterYourFirstNameEditFieldLabel.Text = 'Enter Your First Name:';
            app.LoginFirstNameField = uieditfield(app.UIFigure, 'text');
            app.UploadLoginImageButton = uibutton(app.UIFigure, 'push');
            app.UploadLoginImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadLoginImageButtonPushed, true);
            app.UploadLoginImageButton.Text = 'Browse...';
            app.UploadLoginImageLabel = uilabel(app.UIFigure);
            app.UploadLoginImageLabel.Text = 'Upload a current picture of yourself!';
            app.EmbeddedLoginButton = uibutton(app.UIFigure, 'push');
            app.EmbeddedLoginButton.ButtonPushedFcn = createCallbackFcn(app, @EmbeddedLoginButtonPushed, true);
            app.EmbeddedLoginButton.Text = 'Login!';
            app.LoginImageFilepath = uilabel(app.UIFigure);
            app.LoginImageFilepath.Text = 'No Image Added';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Login

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end