classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CreateAccountButton  matlab.ui.control.Button
        LoginButton          matlab.ui.control.Button
        IdentifaceLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        CreateAcct                      matlab.ui.Figure
        Login                           matlab.ui.Figure
        CreateYourAccountLabel          matlab.ui.control.Label
        EnterYourFirstNameField         matlab.ui.control.EditField
        UploadImageLabel                matlab.ui.control.Label
        UploadImageButton               matlab.ui.control.Button
        EmbeddedCreateAccountButton     matlab.ui.control.Button
        LoginLabel                      matlab.ui.control.Label
        LoginFirstNameField             matlab.ui.control.EditField
        UploadLoginImageButton          matlab.ui.control.Button
        UploadLoginImageLabel           matlab.ui.control.Label
        EmbeddedLoginButton             matlab.ui.control.Button
        CreateAccountImageFilepath      matlab.ui.control.Label
        LoginImageFilepath              matlab.ui.control.Label
        CreateAcctResponse              matlab.ui.Figure
        CreateAcctResponseLabel         matlab.ui.control.Label
        AccountCreatedSuccessfullyMessage matlab.ui.control.Label
        
    end


    methods (Access = private)

        % Button pushed function: CreateAccountButton
        function CreateAccountButtonPushed(app, event)
          openfig(CreateAcct);
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            close(UI);
            openfig(Login);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CreateAccountButton = uibutton(app.UIFigure, 'push');
            app.CreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccountButtonPushed, true);
            app.CreateAccountButton.Text = 'Create Account';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.IdentifaceLabel = uilabel(app.UIFigure);
            app.IdentifaceLabel.Text = 'Identiface';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end